classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        playlist_settings           matlab.ui.container.Menu
        create_playlist             matlab.ui.container.Menu
        add_to_playlist             matlab.ui.container.Menu
        delete_playlist             matlab.ui.container.Menu
        audio_list                  matlab.ui.control.ListBox
        audio_cover                 matlab.ui.control.Image
        playlist_drop_down          matlab.ui.control.DropDown
        butterworth_filter          matlab.ui.container.Panel
        TypeDropDownLabel           matlab.ui.control.Label
        butterworth_type            matlab.ui.control.DropDown
        FrequencyCutOffKnobLabel    matlab.ui.control.Label
        butterworth_cut_off         matlab.ui.control.Knob
        OrderLabel                  matlab.ui.control.Label
        butterworth_order           matlab.ui.control.Spinner
        butterworth_toggle          matlab.ui.control.Switch
        basic_controls              matlab.ui.container.Panel
        VolumeLabel                 matlab.ui.control.Label
        volume_control              matlab.ui.control.Knob
        pause_button                matlab.ui.control.Button
        next_button                 matlab.ui.control.Button
        back_button                 matlab.ui.control.Button
        bass_filter                 matlab.ui.container.Panel
        BassBoostKnobLabel          matlab.ui.control.Label
        bass_boost                  matlab.ui.control.Knob
        BassCutLabel                matlab.ui.control.Label
        bass_cut                    matlab.ui.control.Knob
        bass_toggle                 matlab.ui.control.Switch
        treble_filter               matlab.ui.container.Panel
        treble_toggle               matlab.ui.control.Switch
        TrebleBoostKnobLabel        matlab.ui.control.Label
        treble_boost                matlab.ui.control.Knob
        TrebleCutKnobLabel          matlab.ui.control.Label
        treble_cut                  matlab.ui.control.Knob
        bass_treble_options         matlab.ui.container.Panel
        CenterFrequencyKnob_2Label  matlab.ui.control.Label
        center_frequency            matlab.ui.control.Knob
        SlopeLabel                  matlab.ui.control.Label
        slope                       matlab.ui.control.Knob
        reverb_filter               matlab.ui.container.Panel
        PreDelayKnobLabel           matlab.ui.control.Label
        pre_delay                   matlab.ui.control.Knob
        WetDryMixKnobLabel          matlab.ui.control.Label
        wet_dry_mix                 matlab.ui.control.Knob
        reverb_toggle               matlab.ui.control.Switch
    end

    
    properties (Access = private)
        cover                           % current cover
        audio_dir = 'source\audio\'     % audio directory
        cover_dir = 'source\cover\'     % cover directory 
        all_playlists = {}              % all_playlists
        playlist_name = {'All Songs'}   % playlist names, indx is same as all_playlists
        current_audio                   % current audio (name)
        current_audio_dir               % current audio (directory)
        stop = false                    % boolean value to stop real time audio
        butterworth_order_val = 9       % butterworth default & global values
        butterworth_type_val = 'low'
        butterworth_cut_off_val = 0.5 
        butterworth_toggle_switch 
        file_read                       % dsp audio file reader object
        device_write                    % dsp device writer object
        pause_audio                     % set to true if paused
        volume                          % volume
        bass_val = 1                    % bass gain / cut value
        bass_toggle_switch
        treble_val = 1                  % treble gain / cut value
        treble_toggle_switch
        center_freq = 1500              % center frequency for both bass & treble
        slope_val = 3                   % slope value for both bass & treble 
        pre_delay_val = 0.5             % reverb values
        wet_dry_mix_val = 0.5
        reverb_toggle_switch
        reverb_val
        butter_a                        % values for butterworth filter
        butter_b
    end
    
    methods (Access = private)
        function set_cover(app)
            % dir for audio cover
            cover_dir_details = dir(app.cover_dir);
            % set default audio cover 
            i = imread(strcat(app.cover_dir, 'default.png'));              
            app.audio_cover.ImageSource = i;
            % loop all available covers
            for x = 3:numel(cover_dir_details)
                [~, cover_name, cover_ext] = fileparts(cover_dir_details(x).name);
                if (strcmp(cover_name, app.audio_list.Value))
                    % set cover if available
                    app.cover = imread(strcat(app.cover_dir, cover_name, cover_ext));              
                    app.audio_cover.ImageSource = app.cover;
                end
            end
        end
        function real_time_audio(app)
            % function is run when audio plays, set not paused
            app.pause_button.Text = 'Pause'; 
            app.pause_audio = false;
            % read current audio 
            app.file_read = dsp.AudioFileReader(app.current_audio_dir);
            app.device_write = audioDeviceWriter('SampleRate', app.file_read.SampleRate);
            % reverb filter
            app.reverb_val = reverberator('SampleRate', app.file_read.SampleRate, 'PreDelay', app.pre_delay_val, 'WetDryMix', app.wet_dry_mix_val);
            [~, fs] = audioread(app.current_audio_dir);
            % apply butterworth filter with given vals
            [app.butter_b, app.butter_a] = butter(app.butterworth_order_val, app.butterworth_cut_off_val, app.butterworth_type_val);

            while ~isDone(app.file_read)
                signal = app.file_read();
                signal = signal * app.volume; 
                
                if app.stop
                    break
                end  
                
                if app.butterworth_toggle_switch
                    signal = filter(app.butter_b, app.butter_a, signal);
                end
                
                if app.reverb_toggle_switch
                    signal = app.reverb_val(signal);
                end

                if app.bass_toggle_switch
                    G = app.bass_val;
                    fc = app.center_freq;
                    Q = app.slope_val;
                    K = tan((pi * fc)/fs);
                    V0 = 10^(G/20);
                    root2 = 1/Q; 
                    if(V0 < 1)
                        V0 = 1/V0;
                    end
                    if G > 0
                        b0 = (1 + sqrt(V0)*root2*K + V0*K^2) / (1 + root2*K + K^2);
                        b1 = (2 * (V0*K^2 - 1) ) / (1 + root2*K + K^2);
                        b2 = (1 - sqrt(V0)*root2*K + V0*K^2) / (1 + root2*K + K^2);
                        a1 = (2 * (K^2 - 1) ) / (1 + root2*K + K^2);
                        a2 = (1 - root2*K + K^2) / (1 + root2*K + K^2);
                    elseif G < 0
                        b0 = (1 + root2*K + K^2) / (1 + root2*sqrt(V0)*K + V0*K^2);
                        b1 = (2 * (K^2 - 1) ) / (1 + root2*sqrt(V0)*K + V0*K^2);
                        b2 = (1 - root2*K + K^2) / (1 + root2*sqrt(V0)*K + V0*K^2);
                        a1 = (2 * (V0*K^2 - 1) ) / (1 + root2*sqrt(V0)*K + V0*K^2);
                        a2 = (1 - root2*sqrt(V0)*K + V0*K^2) / (1 + root2*sqrt(V0)*K + V0*K^2);
                    else
                        b0 = V0;
                        b1 = 0;
                        b2 = 0;
                        a1 = 0;
                        a2 = 0;
                    end
                    a = [1, a1, a2];
                    b = [b0, b1, b2];
                    signal = filter(b, a, signal);
                end

                if app.treble_toggle_switch
                    G = app.treble_val;
                    fc = app.center_freq;
                    Q = app.slope_val;
                    K = tan((pi * fc)/fs);
                    V0 = 10^(G/20);
                    root2 = 1/Q; 
                    if(V0 < 1)
                        V0 = 1/V0;
                    end
                    if G > 0
                        b0 = (V0 + root2*sqrt(V0)*K + K^2) / (1 + root2*K + K^2);
                        b1 = (2 * (K^2 - V0) ) / (1 + root2*K + K^2);
                        b2 = (V0 - root2*sqrt(V0)*K + K^2) / (1 + root2*K + K^2);
                        a1 = (2 * (K^2 - 1) ) / (1 + root2*K + K^2);
                        a2 = (1 - root2*K + K^2) / (1 + root2*K + K^2);
                    elseif G < 0
                        b0 = (1 + root2*K + K^2) / (V0 + root2*sqrt(V0)*K + K^2);
                        b1 = (2 * (K^2 - 1) ) / (V0 + root2*sqrt(V0)*K + K^2);
                        b2 = (1 - root2*K + K^2) / (V0 + root2*sqrt(V0)*K + K^2);
                        a1 = (2 * ((K^2)/V0 - 1) ) / (1 + root2/sqrt(V0)*K + (K^2)/V0);
                        a2 = (1 - root2/sqrt(V0)*K + (K^2)/V0) / (1 + root2/sqrt(V0)*K + (K^2)/V0);
                    else
                        b0 = V0;
                        b1 = 0;
                        b2 = 0;
                        a1 = 0;
                        a2 = 0;
                    end
                    a = [1, a1, a2];
                    b = [b0, b1, b2];
                    signal = filter(b, a, signal);
                end

                while (app.pause_audio)
                    pause(0.0000001);
                end
                
                app.device_write(signal);
                pause(0.0000001);
            end
            app.stop = false;
            release(app.file_read);
            release(app.device_write);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            % audio & cover dir
            audio_dir_info = dir(app.audio_dir);
            % create empty array for all audio
            all_audio = [];
            % loop through all available audio in dir, skip "." + ".."
            for x = 3:numel(audio_dir_info)
                % name & ext for each audio file
                [~, name, ext] = fileparts(audio_dir_info(x).name);
                % create struct for each audio file
                audio_struct = struct("name", name, "ext", ext);
                % append to all_audio array
                all_audio = [all_audio audio_struct];
                % set default audio
                if x == 3
                    app.current_audio = name;
                    app.current_audio_dir = strcat(app.audio_dir, name, ext);
                end
            end
            % append all_audio to all_playlists
            app.all_playlists = [app.all_playlists all_audio];
            % set playlist_drop_down items and default to 'All Songs'
            app.playlist_drop_down.Items = app.playlist_name;
            app.playlist_drop_down.Value = app.playlist_name(1);
            % set audio_list items and default value
            app.audio_list.Items = {app.all_playlists{1}.name};
            % set audio cover
            set_cover(app)
            % set butterworth default filters
            app.butterworth_type.Items = {'low', 'high'}; 
            app.butterworth_type.Value = app.butterworth_type_val;
            % set real time audio values
            app.file_read = dsp.AudioFileReader(app.current_audio_dir);
            app.device_write = audioDeviceWriter('SampleRate', app.file_read.SampleRate);
            % default volume
            app.volume_control.Value = 2;
            app.volume = 0.2*2;
        end

        % Value changed function: audio_list
        function audio_list_val_changed(app, event)
            % clear audio objects
            release(app.file_read);
            release(app.device_write)
            % loop through 'All Songs'
            for x = 1:numel(app.all_playlists{1})
                % if song selected matches song in 'All Songs'
                if strcmp(app.audio_list.Value, app.all_playlists{1}(x).name)
                    % set app.current_audio to name of audio list value
                    app.current_audio = app.all_playlists{1}(x).name;
                    % set app.current_audio_dir to directory of audio
                    app.current_audio_dir = strcat(app.audio_dir, app.all_playlists{1}(x).name, app.all_playlists{1}(x).ext);
                end
            end
            set_cover(app)
            app.pause_button.Text = 'Pause';
            real_time_audio(app);
        end

        % Close request function: UIFigure
        function exit(app, event)
            %save('playlist_var', app.all_playlists, app.playlist_name)
            app.stop = true;
            delete(app)
            clear sound;
        end

        % Button pushed function: pause_button
        function pause_button_select(app, event)
            % if not paused, pause else play   
            if app.pause_audio == false 
                app.pause_audio = true;
                app.pause_button.Text = 'Play'; 
            else
                app.pause_audio = false;
                app.pause_button.Text = 'Pause'; 
            end
        end

        % Button pushed function: next_button
        function next_button_select(app, event)
            % loop all playlist names
            for x = 1:numel(app.playlist_name)
                % if playlist is the selected in playlist_drop_down
                if strcmp(app.playlist_name(x), app.playlist_drop_down.Value)
                    % loop all audio in playlist
                    for a = 1:numel(app.all_playlists{x})
                        % current selected playlist
                        c_playlist = app.all_playlists{x};
                        % no audio selected
                        if isempty(app.audio_list.Value)
                            break     
                        % if first audio in playlist
                        elseif (strcmp(c_playlist(a).name, app.audio_list.Value) && a == numel(c_playlist))
                            app.audio_list.Value = c_playlist(1).name;
                            app.current_audio = c_playlist(1).name;
                            app.current_audio_dir = strcat(app.audio_dir, c_playlist(1).name, c_playlist(1).ext);
                            real_time_audio(app);
                            break
                        % if not first audio in playlist
                        elseif (strcmp(c_playlist(a).name, app.audio_list.Value) && a < numel(c_playlist))
                            app.audio_list.Value = c_playlist(a+1).name;
                            app.current_audio = c_playlist(a+1).name;
                            app.current_audio_dir = strcat(app.audio_dir, c_playlist(a+1).name, c_playlist(a+1).ext);
                            real_time_audio(app);
                            break
                        end
                    end
                end
            end
        end

        % Button pushed function: back_button
        function back_button_select(app, event)
            % loop all playlist names
            for x = 1:numel(app.playlist_name)
                % if playlist is the selected in playlist_drop_down
                if strcmp(app.playlist_name(x), app.playlist_drop_down.Value)
                    % loop all audio in playlist
                    for a = 1:numel(app.all_playlists{x})
                        % current selected playlist
                        c_playlist = app.all_playlists{x};
                        % no audio selected
                        if isempty(app.audio_list.Value)
                            break
                        elseif (strcmp(c_playlist(a).name, app.audio_list.Value) && a == 1)
                            app.audio_list.Value = c_playlist(numel(c_playlist)).name;
                            app.current_audio = c_playlist(numel(c_playlist)).name;
                            app.current_audio_dir = strcat(app.audio_dir, c_playlist(numel(c_playlist)).name, c_playlist(numel(c_playlist)).ext);
                            real_time_audio(app);
                            break
                        elseif (strcmp(c_playlist(a).name, app.audio_list.Value) && a ~= 1)
                            app.audio_list.Value = c_playlist(a-1).name;
                            app.current_audio = c_playlist(a-1).name;
                            app.current_audio_dir = strcat(app.audio_dir, c_playlist(a-1).name, c_playlist(a-1).ext);
                            real_time_audio(app);
                            break
                        end
                    end
                end
            end
        end

        % Menu selected function: create_playlist
        function create_playlist_select(app, event)
            prompt = {'Playlist Name:'};
            dlgtitle = ''; 
            dims = [1 20];
            % ask user for playlist name
            name = inputdlg(prompt, dlgtitle, dims);
            % check playlist name is not empty
            while name == ""
                name = inputdlg(prompt, dlgtitle, dims);
            end
            % add new playlist name to playlist_name & update items
            app.playlist_name = [app.playlist_name name];
            app.playlist_drop_down.Items = app.playlist_name; 
            % create struct for new playlist
            new_struct = struct("name", {}, "ext", {});
            % add struct to end of all_playlists
            app.all_playlists(numel(app.all_playlists) + 1) = {new_struct};
        end

        % Menu selected function: add_to_playlist
        function add_to_playlist_select(app, event)
            % select playlist to add to
            [indx, ~] = listdlg('ListString', app.playlist_name, 'SelectionMode', 'Single');
            if isempty(app.audio_list.Value)
                errordlg('No audio selected', 'Playlist Error');
            elseif strcmp(app.playlist_name{indx}, 'All Songs')
                errordlg('Cannot add to default playlist', 'Playlist Error');
            else
                for x = 1:numel(app.all_playlists{1})
                    if strcmp(app.all_playlists{1}(x).name, app.audio_list.Value)
                        % throw error if audio is already in playlist
                        if any(strcmp({app.all_playlists{indx}.name}, app.all_playlists{1}(x).name))
                            errordlg('Already in playlist', 'Playlist Error');
                            break
                        else
                            % add current audio to playlist chose
                            app.all_playlists{indx} = [app.all_playlists{indx} app.all_playlists{1}(x)];
                        end
                    end
                end
            end
            
        end

        % Menu selected function: delete_playlist
        function delete_playlist_select(app, event)
            if strcmp(app.playlist_drop_down.Value, 'All Songs')
                errordlg('Cannot delete default playlist', 'Playlist Error');
            else    
                for x = 1:numel(app.playlist_name)
                    if strcmp(app.playlist_name{x}, app.playlist_drop_down.Value)
                        app.playlist_name(x) = [];
                        app.all_playlists(x) = [];
                    end
                end
            end
            % change back to default playlist
            app.playlist_drop_down.Items = app.playlist_name;
            app.playlist_drop_down.Value = app.playlist_name(1);
            app.audio_list.Items = {app.all_playlists{1}.name};
        end

        % Value changed function: playlist_drop_down
        function playlist_drop_down_changed(app, event)
            % set audio_list items when each playlist is selected
            for x = 1:numel(app.playlist_name)
                if strcmp(app.playlist_name{x}, app.playlist_drop_down.Value)  
                    app.audio_list.Items = {app.all_playlists{x}.name};
                    if any(strcmp(app.audio_list.Items, app.current_audio))
                        app.audio_list.Value = app.current_audio;
                    else
                        app.audio_list.Value = {};
                    end
                end
            end      
        end

        % Value changed function: volume_control
        function volume_control_changed(app, event)
            app.volume = 0.2*app.volume_control.Value;
        end

        % Value changed function: butterworth_type
        function butterworth_type_changed(app, event)
            app.butterworth_type_val = app.butterworth_type.Value;
            [app.butter_b, app.butter_a] = butter(app.butterworth_order_val, app.butterworth_cut_off_val, app.butterworth_type_val);
        end

        % Value changed function: butterworth_cut_off
        function butterworth_cut_off_changed(app, event)
            app.butterworth_cut_off_val = app.butterworth_cut_off.Value;
            [app.butter_b, app.butter_a] = butter(app.butterworth_order_val, app.butterworth_cut_off_val, app.butterworth_type_val);
        end

        % Value changed function: butterworth_order
        function butterworth_order_changed(app, event)
            app.butterworth_order_val = app.butterworth_order.Value;
            [app.butter_b, app.butter_a] = butter(app.butterworth_order_val, app.butterworth_cut_off_val, app.butterworth_type_val);
        end

        % Value changed function: butterworth_toggle
        function butterworth_toggle_changed(app, event)
            % set app.butterworth_toggle_switch to T | F
            if strcmp(app.butterworth_toggle.Value, 'On')
                app.butterworth_toggle_switch = true;
            else
                app.butterworth_toggle_switch = false;
            end
        end

        % Value changed function: bass_boost
        function bass_boost_changed(app, event)
            app.bass_val = app.bass_boost.Value;
        end

        % Value changed function: bass_cut
        function bass_cut_changed(app, event)
            app.bass_val = app.bass_cut.Value;  
        end

        % Value changed function: bass_toggle
        function bass_toggle_changed(app, event)
            % set app.bass_toggle_switch to T | F
            if strcmp(app.bass_toggle.Value, 'On')
                app.bass_toggle_switch = true;
            else
                app.bass_toggle_switch = false;
            end
        end

        % Value changed function: treble_boost
        function treble_boost_changed(app, event)
            app.treble_val = app.treble_boost.Value;       
        end

        % Value changed function: treble_cut
        function treble_cut_changed(app, event)
            app.treble_val = app.treble_cut.Value;
        end

        % Value changed function: treble_toggle
        function treble_toggle_changed(app, event)
             % set app.treble_toggle_switch to T | F
            if strcmp(app.treble_toggle.Value, 'On')
                app.treble_toggle_switch = true;
            else
                app.treble_toggle_switch = false;
            end   
        end

        % Value changed function: center_frequency
        function center_frequency_changed(app, event)
            app.center_freq = app.center_frequency.Value;
        end

        % Value changed function: slope
        function slope_changed(app, event)
            app.slope_val = app.slope.Value;
        end

        % Value changed function: pre_delay
        function pre_delay_changed(app, event)
            app.pre_delay_val = app.pre_delay.Value;
            app.reverb_val = reverberator('SampleRate', app.file_read.SampleRate, 'PreDelay', app.pre_delay_val, 'WetDryMix', app.wet_dry_mix_val);
        end

        % Value changed function: wet_dry_mix
        function wet_dry_mix_changed(app, event)
            app.wet_dry_mix_val = app.wet_dry_mix.Value;
            app.reverb_val = reverberator('SampleRate', app.file_read.SampleRate, 'PreDelay', app.pre_delay_val, 'WetDryMix', app.wet_dry_mix_val);
        end

        % Value changed function: reverb_toggle
        function reverb_toggle_changed(app, event)
            % set app.reverb_toggle_switch to T | F
            if strcmp(app.reverb_toggle.Value, 'On')
                app.reverb_toggle_switch = true;
            else
                app.reverb_toggle_switch = false;
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @exit, true);
            app.playlist_settings = uimenu(app.UIFigure);
            app.playlist_settings.Text = 'Playlist Settings';
            app.create_playlist = uimenu(app.playlist_settings);
            app.create_playlist.MenuSelectedFcn = createCallbackFcn(app, @create_playlist_select, true);
            app.create_playlist.Text = 'Create Playlist';
            app.add_to_playlist = uimenu(app.playlist_settings);
            app.add_to_playlist.MenuSelectedFcn = createCallbackFcn(app, @add_to_playlist_select, true);
            app.add_to_playlist.Text = 'Add to Playlist';
            app.delete_playlist = uimenu(app.playlist_settings);
            app.delete_playlist.MenuSelectedFcn = createCallbackFcn(app, @delete_playlist_select, true);
            app.delete_playlist.Text = 'Delete Playlist';
            app.audio_list = uilistbox(app.UIFigure);
            app.audio_list.Items = {};
            app.audio_list.ValueChangedFcn = createCallbackFcn(app, @audio_list_val_changed, true);
            app.audio_list.Value = {};
            app.audio_cover = uiimage(app.UIFigure);
            app.playlist_drop_down = uidropdown(app.UIFigure);
            app.playlist_drop_down.Items = {};
            app.playlist_drop_down.ValueChangedFcn = createCallbackFcn(app, @playlist_drop_down_changed, true);
            app.playlist_drop_down.Value = {};
            app.butterworth_filter = uipanel(app.UIFigure);
            app.butterworth_filter.Title = 'Butterworth Filter';
            app.TypeDropDownLabel = uilabel(app.butterworth_filter);
            app.TypeDropDownLabel.Text = 'Type';
            app.butterworth_type = uidropdown(app.butterworth_filter);
            app.butterworth_type.Items = {};
            app.butterworth_type.ValueChangedFcn = createCallbackFcn(app, @butterworth_type_changed, true);
            app.butterworth_type.Value = {};
            app.FrequencyCutOffKnobLabel = uilabel(app.butterworth_filter);
            app.FrequencyCutOffKnobLabel.Text = 'Frequency Cut Off';
            app.butterworth_cut_off = uiknob(app.butterworth_filter, 'continuous');
            app.butterworth_cut_off.Limits = [0.01 0.99];
            app.butterworth_cut_off.ValueChangedFcn = createCallbackFcn(app, @butterworth_cut_off_changed, true);
            app.butterworth_cut_off.Value = 0.01;
            app.OrderLabel = uilabel(app.butterworth_filter);
            app.OrderLabel.Text = 'Order';
            app.butterworth_order = uispinner(app.butterworth_filter);
            app.butterworth_order.Limits = [1 20];
            app.butterworth_order.ValueChangedFcn = createCallbackFcn(app, @butterworth_order_changed, true);
            app.butterworth_order.Value = 1;
            app.butterworth_toggle = uiswitch(app.butterworth_filter, 'slider');
            app.butterworth_toggle.ValueChangedFcn = createCallbackFcn(app, @butterworth_toggle_changed, true);
            app.basic_controls = uipanel(app.UIFigure);
            app.basic_controls.Title = 'Basic Controls';
            app.VolumeLabel = uilabel(app.basic_controls);
            app.VolumeLabel.Text = 'Volume';
            app.volume_control = uiknob(app.basic_controls, 'continuous');
            app.volume_control.Limits = [0 10];
            app.volume_control.ValueChangedFcn = createCallbackFcn(app, @volume_control_changed, true);
            app.pause_button = uibutton(app.basic_controls, 'push');
            app.pause_button.ButtonPushedFcn = createCallbackFcn(app, @pause_button_select, true);
            app.pause_button.Text = {'Play'; ''};
            app.next_button = uibutton(app.basic_controls, 'push');
            app.next_button.ButtonPushedFcn = createCallbackFcn(app, @next_button_select, true);
            app.next_button.Text = 'Next';
            app.back_button = uibutton(app.basic_controls, 'push');
            app.back_button.ButtonPushedFcn = createCallbackFcn(app, @back_button_select, true);
            app.back_button.Text = 'Back';
            app.bass_filter = uipanel(app.UIFigure);
            app.bass_filter.Title = 'Bass';
            app.BassBoostKnobLabel = uilabel(app.bass_filter);
            app.BassBoostKnobLabel.Text = 'Bass Boost';
            app.bass_boost = uiknob(app.bass_filter, 'continuous');
            app.bass_boost.Limits = [1 20];
            app.bass_boost.ValueChangedFcn = createCallbackFcn(app, @bass_boost_changed, true);
            app.bass_boost.Value = 1;
            app.BassCutLabel = uilabel(app.bass_filter);
            app.BassCutLabel.Text = 'Bass Cut';
            app.bass_cut = uiknob(app.bass_filter, 'continuous');
            app.bass_cut.Limits = [0 1];
            app.bass_cut.ValueChangedFcn = createCallbackFcn(app, @bass_cut_changed, true);
            app.bass_toggle = uiswitch(app.bass_filter, 'slider');
            app.bass_toggle.ValueChangedFcn = createCallbackFcn(app, @bass_toggle_changed, true);
            app.treble_filter = uipanel(app.UIFigure);
            app.treble_filter.Title = 'Treble';
            app.treble_toggle = uiswitch(app.treble_filter, 'slider');
            app.treble_toggle.ValueChangedFcn = createCallbackFcn(app, @treble_toggle_changed, true);
            app.TrebleBoostKnobLabel = uilabel(app.treble_filter);
            app.TrebleBoostKnobLabel.Text = 'Treble Boost';
            app.treble_boost = uiknob(app.treble_filter, 'continuous');
            app.treble_boost.Limits = [1 20];
            app.treble_boost.ValueChangedFcn = createCallbackFcn(app, @treble_boost_changed, true);
            app.treble_boost.Value = 1;
            app.TrebleCutKnobLabel = uilabel(app.treble_filter);
            app.TrebleCutKnobLabel.Text = 'Treble Cut';
            app.treble_cut = uiknob(app.treble_filter, 'continuous');
            app.treble_cut.Limits = [0 1];
            app.treble_cut.ValueChangedFcn = createCallbackFcn(app, @treble_cut_changed, true);
            app.bass_treble_options = uipanel(app.UIFigure);
            app.CenterFrequencyKnob_2Label = uilabel(app.bass_treble_options);
            app.CenterFrequencyKnob_2Label.Text = 'Center Frequency';
            app.center_frequency = uiknob(app.bass_treble_options, 'continuous');
            app.center_frequency.Limits = [0 20000];
            app.center_frequency.ValueChangedFcn = createCallbackFcn(app, @center_frequency_changed, true);
            app.SlopeLabel = uilabel(app.bass_treble_options);
            app.SlopeLabel.Text = 'Slope';
            app.slope = uiknob(app.bass_treble_options, 'continuous');
            app.slope.Limits = [0 20];
            app.slope.ValueChangedFcn = createCallbackFcn(app, @slope_changed, true);
            app.reverb_filter = uipanel(app.UIFigure);
            app.reverb_filter.Title = 'Reverb';
            app.PreDelayKnobLabel = uilabel(app.reverb_filter);
            app.PreDelayKnobLabel.Text = 'Pre Delay';
            app.pre_delay = uiknob(app.reverb_filter, 'continuous');
            app.pre_delay.Limits = [0 1];
            app.pre_delay.ValueChangedFcn = createCallbackFcn(app, @pre_delay_changed, true);
            app.WetDryMixKnobLabel = uilabel(app.reverb_filter);
            app.WetDryMixKnobLabel.Text = 'Wet Dry Mix';
            app.wet_dry_mix = uiknob(app.reverb_filter, 'continuous');
            app.wet_dry_mix.Limits = [0 1];
            app.wet_dry_mix.ValueChangedFcn = createCallbackFcn(app, @wet_dry_mix_changed, true);
            app.reverb_toggle = uiswitch(app.reverb_filter, 'slider');
            app.reverb_toggle.ValueChangedFcn = createCallbackFcn(app, @reverb_toggle_changed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end